classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BrowseFileButton                matlab.ui.control.Button
        SaveImageButton                 matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        RestorationMethodDropDown       matlab.ui.control.DropDown
        RestorationMethodDropDownLabel  matlab.ui.control.Label
        InputFileEditField              matlab.ui.control.EditField
        InputFileEditFieldLabel         matlab.ui.control.Label
        BlueChannelCheckBox             matlab.ui.control.CheckBox
        GreenChannelCheckBox            matlab.ui.control.CheckBox
        RedChannelCheckBox              matlab.ui.control.CheckBox
        InputImage                      matlab.ui.control.Image
        HighBoostFilterInputPanel       matlab.ui.container.Panel
        HBF_aEditField                  matlab.ui.control.NumericEditField
        cLabel_2                        matlab.ui.control.Label
        HBF_cEditField                  matlab.ui.control.NumericEditField
        gLabel_7                        matlab.ui.control.Label
        UnsharpMaskingInputPanel        matlab.ui.container.Panel
        UM_cEditField                   matlab.ui.control.NumericEditField
        gLabel_6                        matlab.ui.control.Label
        HistSpecInputPanel              matlab.ui.container.Panel
        BrowseFileButton_2              matlab.ui.control.Button
        SpecImage_2                     matlab.ui.control.Image
        SpecInputFileEditField          matlab.ui.control.EditField
        InputSpecificationImageLabel    matlab.ui.control.Label
        SpecHistAxes_2                  matlab.ui.control.UIAxes
        GaussianFilteringInputPanel     matlab.ui.container.Panel
        GF_sigma                        matlab.ui.control.NumericEditField
        gLabel_5                        matlab.ui.control.Label
        AverageFilteringInputPanel      matlab.ui.container.Panel
        AF_kernelsz                     matlab.ui.control.NumericEditField
        gLabel_4                        matlab.ui.control.Label
        MedianFilteringInputPanel       matlab.ui.container.Panel
        MF_kernelsz                     matlab.ui.control.NumericEditField
        gLabel_3                        matlab.ui.control.Label
        PiecewiseLinearTransformInputPanel  matlab.ui.container.Panel
        PLT_yb                          matlab.ui.control.NumericEditField
        minLabel_3                      matlab.ui.control.Label
        PLT_ya                          matlab.ui.control.NumericEditField
        minLabel_2                      matlab.ui.control.Label
        PLT_b                           matlab.ui.control.NumericEditField
        minLabel                        matlab.ui.control.Label
        PLT_a                           matlab.ui.control.NumericEditField
        maxLabel                        matlab.ui.control.Label
        InverseLogTransformInputPanel   matlab.ui.container.Panel
        kexpkc1Label                    matlab.ui.control.Label
        ILT_cEditField                  matlab.ui.control.NumericEditField
        gLabel_2                        matlab.ui.control.Label
        PowerTransformInputPanel        matlab.ui.container.Panel
        kckgLabel                       matlab.ui.control.Label
        PT_cEditField                   matlab.ui.control.NumericEditField
        cLabel                          matlab.ui.control.Label
        PT_gEditField                   matlab.ui.control.NumericEditField
        gLabel                          matlab.ui.control.Label
        ImageBrigthenInputPanel         matlab.ui.container.Panel
        kakbLabel                       matlab.ui.control.Label
        Brighten_bEditField             matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        Brighten_aEditField             matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        PanelTemplate                   matlab.ui.container.Panel
        OutputImage                     matlab.ui.control.UIAxes
        OutputHistAxes                  matlab.ui.control.UIAxes
        InputHistAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        InputImage_val
        OutputImage_val
        SpecImage_val
    end
    
    methods (Access = private)
        
        function SetHistogram(app, axes, img)
            hist_data = myHist(img);
            [L, num_ch] = size(hist_data);
            color_values = 0:(L - 1);
            cla(axes)
            hold(axes, "on");
            for c = 1:num_ch
                if IsChannelDisplayed(app, c)
                    color_name = GetBarColor(app, c);
                    bar(axes, color_values, hist_data(:, c), color_name, color_name);
                end 
            end
            hold(axes, "off");
        end
        
        function color_name = GetBarColor(~, idx)
            if idx == 1
                color_name = 'red';
            elseif idx == 2
                color_name = 'green';
            else
                color_name = 'blue';
            end
        end
        
        function displayed = IsChannelDisplayed(app, idx)
            if idx == 1
                displayed = app.RedChannelCheckBox.Value;
            elseif idx == 2
                displayed = app.GreenChannelCheckBox.Value;
            else
                displayed = app.BlueChannelCheckBox.Value;
            end
        end
        
        function ChangeWindowWidth(app, newWidth)
            app.UIFigure.Position = [100 100 newWidth 640];
        end
        
        function ResetFigure(app)
            app.ImageBrigthenInputPanel.Visible = "off";
            app.HistSpecInputPanel.Visible = 'off';
            app.PowerTransformInputPanel.Visible = "off";
            app.InverseLogTransformInputPanel.Visible = "off";
            app.PiecewiseLinearTransformInputPanel.Visible = "off";
            app.AverageFilteringInputPanel.Visible = "off";
            app.MedianFilteringInputPanel.Visible = "off";
            app.GaussianFilteringInputPanel.Visible = "off";
            app.HighBoostFilterInputPanel.Visible = "off";
            app.UnsharpMaskingInputPanel.Visible = "off";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InputFileEditField
        function InputFileEditFieldValueChanged(app, event)
            value = app.InputFileEditField.Value;
            app.InputImage.ImageSource = value;
            app.InputImage_val = imread(value);
            SetHistogram(app, app.InputHistAxes, app.InputImage_val);
        end

        % Value changed function: BlueChannelCheckBox, 
        % ...and 2 other components
        function ResetHistogram(app, event)
            SetHistogram(app, app.InputHistAxes, app.InputImage_val);
            SetHistogram(app, app.OutputHistAxes, app.OutputImage_val);
        end

        % Value changed function: RestorationMethodDropDown
        function RestorationMethodDropDownValueChanged(app, event)
            value = app.RestorationMethodDropDown.Value;
            ResetFigure(app);
            if strcmp('Image Brightening', value)
                app.ImageBrigthenInputPanel.Visible = "on";
            elseif strcmp('Power Transform', value)
                app.PowerTransformInputPanel.Visible = "on";
            elseif strcmp('Piecewise Linear Transform', value)
                app.PiecewiseLinearTransformInputPanel.Visible = "on";
            elseif strcmp('Inverse Log Transform', value)
                app.InverseLogTransformInputPanel.Visible = "on";
            elseif strcmp('Histogram Specification', value)
                app.HistSpecInputPanel.Visible = "on";
            elseif strcmp('Averaging Filter', value)
                app.AverageFilteringInputPanel.Visible = "on";
            elseif strcmp('Median Filter', value)
                app.MedianFilteringInputPanel.Visible = "on";
            elseif strcmp('Gaussian Filter', value)
                app.GaussianFilteringInputPanel.Visible = "on";
            elseif strcmp('High Boost Filter', value)
                app.HighBoostFilterInputPanel.Visible = "on";
            elseif strcmp('Unsharp Masking', value)
                app.UnsharpMaskingInputPanel.Visible = "on";
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            method = app.RestorationMethodDropDown.Value;
            if strcmp('Image Brightening', method)
                app.OutputImage_val = imgBrighten(app.InputImage_val, app.Brighten_bEditField.Value, app.Brighten_aEditField.Value);
            elseif strcmp('Contrast Stretching', method)
                app.OutputImage_val = contrastStretch(app.InputImage_val);
            elseif strcmp ('Power Transform', method)
                app.OutputImage_val = powerTransform(app.InputImage_val, app.PT_cEditField.Value, app.PT_gEditField.Value);
            elseif strcmp ('Inverse Log Transform', method)
                app.OutputImage_val = invLogTransform(app.InputImage_val, app.ILT_cEditField.Value);
            elseif strcmp('Piecewise Linear Transform', method)
                app.OutputImage_val = piecewiseLinearTransform(app.InputImage_val, app.PLT_a.Value, app.PLT_b.Value, app.PLT_ya.Value, app.PLT_yb.Value);
            elseif strcmp('Histogram Equalization', method)
                app.OutputImage_val = histEqualize(app.InputImage_val);
            elseif strcmp('Histogram Specification', method)
                app.OutputImage_val = histSpecify(app.InputImage_val, app.SpecImage_val);
            elseif strcmp('Averaging Filter', method)
                app.OutputImage_val = avgFiltering(app.InputImage_val, app.AF_kernelsz.Value);
            elseif strcmp('Median Filter', method)
                app.OutputImage_val = medianFiltering(app.InputImage_val, app.MF_kernelsz.Value);
            elseif strcmp('Gaussian Filter', method)
                app.OutputImage_val = gaussianFiltering(app.InputImage_val, app.GF_sigma.Value);
            elseif strcmp('High Boost Filter', method)
                c = app.HBF_cEditField.Value;
                kernel = [-c -c -c; -c (8*c+1) -c; -c -c -c];
                [row, col, num_ch] = size(app.InputImage_val);
                highpass = zeros(row, col, num_ch, 'like', app.InputImage_val);
                for i = 1:num_ch
                    highpass(:,:,i) = convolution(app.InputImage_val(:,:,i), kernel);
                end
                app.OutputImage_val = highBoostFiltering(app.InputImage_val, highpass, app.HBF_aEditField.Value);
            elseif strcmp('Unsharp Masking', method)
                c = app.UM_cEditField.Value;
                kernel = [-c -c -c; -c (8*c+1) -c; -c -c -c];
                [row, col, num_ch] = size(app.InputImage_val);
                highpass = zeros(row, col, num_ch, 'like', app.InputImage_val);
                for i = 1:num_ch
                    highpass(:,:,i) = convolution(app.InputImage_val(:,:,i), kernel);
                end
                app.OutputImage_val = unsharpMasking(app.InputImage_val, highpass);
            end
            imshow(app.OutputImage_val, 'Parent', app.OutputImage);
            app.OutputImage.XLim = [0, size(app.OutputImage_val, 2)];
            app.OutputImage.YLim = [0, size(app.OutputImage_val, 1)];
            SetHistogram(app, app.OutputHistAxes, app.OutputImage_val);
        end

        % Value changed function: SpecInputFileEditField
        function SpecInputFileEditFieldValueChanged(app, event)
            value = app.SpecInputFileEditField.Value;
            app.SpecImage_2.ImageSource = value;
            app.SpecImage_val = imread(value);
            SetHistogram(app, app.SpecHistAxes_2, app.SpecImage_val);
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)

            [parentFolder, filename, fileExt] = fileparts(app.InputFileEditField.Value);
            imwrite(app.OutputImage_val, parentFolder + "/" + filename + "_01" + fileExt);
        end

        % Button pushed function: BrowseFileButton
        function BrowseFileButtonPushed(app, event)
            [filename, pathname] = uigetfile('../test_case/*.*');
            if isequal(filename, 0)
                
            else
                figure(app.UIFigure);
                app.InputFileEditField.Value = fullfile(pathname, filename);
                InputFileEditFieldValueChanged(app, event);
                
            end
        end

        % Button pushed function: BrowseFileButton_2
        function BrowseFileButton_2Pushed(app, event)
            [filename, pathname] = uigetfile('../test_case/Specification*.*');
            if isequal(filename, 0)

            else
                figure(app.UIFigure);
                app.SpecInputFileEditField.Value = fullfile(pathname, filename);
                SpecInputFileEditFieldValueChanged(app, event);

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputHistAxes = uiaxes(app.UIFigure);
            title(app.InputHistAxes, 'Input Image Histogram')
            xlabel(app.InputHistAxes, 'X')
            ylabel(app.InputHistAxes, 'Y')
            zlabel(app.InputHistAxes, 'Z')
            app.OutputHistAxes = uiaxes(app.UIFigure);
            title(app.OutputHistAxes, 'Output Image Histogram')
            xlabel(app.OutputHistAxes, 'X')
            ylabel(app.OutputHistAxes, 'Y')
            zlabel(app.OutputHistAxes, 'Z')
            app.OutputImage = uiaxes(app.UIFigure);
            app.OutputImage.XTick = [];
            app.OutputImage.YTick = [];
            app.OutputImage.ZTick = [];
            app.PanelTemplate = uipanel(app.UIFigure);
            app.PanelTemplate.Visible = 'off';
            app.ImageBrigthenInputPanel = uipanel(app.UIFigure);
            app.ImageBrigthenInputPanel.Visible = 'off';
            app.aEditFieldLabel = uilabel(app.ImageBrigthenInputPanel);
            app.aEditFieldLabel.Text = 'a';
            app.Brighten_aEditField = uieditfield(app.ImageBrigthenInputPanel, 'numeric');
            app.Brighten_aEditField.Limits = [-255 255];
            app.Brighten_aEditField.Value = 1;
            app.bEditFieldLabel = uilabel(app.ImageBrigthenInputPanel);
            app.bEditFieldLabel.Text = 'b';
            app.Brighten_bEditField = uieditfield(app.ImageBrigthenInputPanel, 'numeric');
            app.Brighten_bEditField.Limits = [-255 255];
            app.kakbLabel = uilabel(app.ImageBrigthenInputPanel);
            app.kakbLabel.Text = 'k'' = a * k + b';
            app.PowerTransformInputPanel = uipanel(app.UIFigure);
            app.PowerTransformInputPanel.Visible = 'off';
            app.gLabel = uilabel(app.PowerTransformInputPanel);
            app.gLabel.Text = 'g';
            app.PT_gEditField = uieditfield(app.PowerTransformInputPanel, 'numeric');
            app.PT_gEditField.Limits = [-255 255];
            app.PT_gEditField.Value = 1;
            app.cLabel = uilabel(app.PowerTransformInputPanel);
            app.cLabel.Text = 'c';
            app.PT_cEditField = uieditfield(app.PowerTransformInputPanel, 'numeric');
            app.PT_cEditField.Limits = [-255 255];
            app.PT_cEditField.Value = 1;
            app.kckgLabel = uilabel(app.PowerTransformInputPanel);
            app.kckgLabel.Text = 'k'' = c * k ^ g';
            app.InverseLogTransformInputPanel = uipanel(app.UIFigure);
            app.InverseLogTransformInputPanel.Visible = 'off';
            app.gLabel_2 = uilabel(app.InverseLogTransformInputPanel);
            app.gLabel_2.Text = 'g';
            app.ILT_cEditField = uieditfield(app.InverseLogTransformInputPanel, 'numeric');
            app.ILT_cEditField.Limits = [-255 255];
            app.ILT_cEditField.Value = 1;
            app.kexpkc1Label = uilabel(app.InverseLogTransformInputPanel);
            app.kexpkc1Label.Text = 'k'' = exp(k ^ c) - 1';
            app.PiecewiseLinearTransformInputPanel = uipanel(app.UIFigure);
            app.PiecewiseLinearTransformInputPanel.Visible = 'off';
            app.maxLabel = uilabel(app.PiecewiseLinearTransformInputPanel);
            app.maxLabel.Text = 'a';
            app.PLT_a = uieditfield(app.PiecewiseLinearTransformInputPanel, 'numeric');
            app.PLT_a.Limits = [0 255];
            app.minLabel = uilabel(app.PiecewiseLinearTransformInputPanel);
            app.minLabel.Text = 'b';
            app.PLT_b = uieditfield(app.PiecewiseLinearTransformInputPanel, 'numeric');
            app.PLT_b.Limits = [0 255];
            app.PLT_b.Value = 255;
            app.minLabel_2 = uilabel(app.PiecewiseLinearTransformInputPanel);
            app.minLabel_2.Text = 'ya';
            app.PLT_ya = uieditfield(app.PiecewiseLinearTransformInputPanel, 'numeric');
            app.PLT_ya.Limits = [0 255];
            app.minLabel_3 = uilabel(app.PiecewiseLinearTransformInputPanel);
            app.minLabel_3.Text = 'yb';
            app.PLT_yb = uieditfield(app.PiecewiseLinearTransformInputPanel, 'numeric');
            app.PLT_yb.Limits = [0 255];
            app.PLT_yb.Value = 255;
            app.MedianFilteringInputPanel = uipanel(app.UIFigure);
            app.MedianFilteringInputPanel.Visible = 'off';
            app.gLabel_3 = uilabel(app.MedianFilteringInputPanel);
            app.gLabel_3.Text = 'kernel size';
            app.MF_kernelsz = uieditfield(app.MedianFilteringInputPanel, 'numeric');
            app.MF_kernelsz.Limits = [0 Inf];
            app.MF_kernelsz.Value = 1;
            app.AverageFilteringInputPanel = uipanel(app.UIFigure);
            app.AverageFilteringInputPanel.Visible = 'off';
            app.gLabel_4 = uilabel(app.AverageFilteringInputPanel);
            app.gLabel_4.Text = 'kernel size';
            app.AF_kernelsz = uieditfield(app.AverageFilteringInputPanel, 'numeric');
            app.AF_kernelsz.Limits = [0 Inf];
            app.AF_kernelsz.Value = 1;
            app.GaussianFilteringInputPanel = uipanel(app.UIFigure);
            app.GaussianFilteringInputPanel.Visible = 'off';
            app.gLabel_5 = uilabel(app.GaussianFilteringInputPanel);
            app.gLabel_5.Text = 'sigma';
            app.GF_sigma = uieditfield(app.GaussianFilteringInputPanel, 'numeric');
            app.GF_sigma.Limits = [0 Inf];
            app.GF_sigma.Value = 1;
            app.HistSpecInputPanel = uipanel(app.UIFigure);
            app.HistSpecInputPanel.Visible = 'off';
            app.SpecHistAxes_2 = uiaxes(app.HistSpecInputPanel);
            title(app.SpecHistAxes_2, 'Specification Image Histogram')
            xlabel(app.SpecHistAxes_2, 'X')
            ylabel(app.SpecHistAxes_2, 'Y')
            zlabel(app.SpecHistAxes_2, 'Z')
            app.InputSpecificationImageLabel = uilabel(app.HistSpecInputPanel);
            app.InputSpecificationImageLabel.Text = 'Input Specification Image';
            app.SpecInputFileEditField = uieditfield(app.HistSpecInputPanel, 'text');
            app.SpecInputFileEditField.ValueChangedFcn = createCallbackFcn(app, @SpecInputFileEditFieldValueChanged, true);
            app.SpecImage_2 = uiimage(app.HistSpecInputPanel);
            app.BrowseFileButton_2 = uibutton(app.HistSpecInputPanel, 'push');
            app.BrowseFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButton_2Pushed, true);
            app.BrowseFileButton_2.Text = 'Browse File';
            app.UnsharpMaskingInputPanel = uipanel(app.UIFigure);
            app.UnsharpMaskingInputPanel.Visible = 'off';
            app.gLabel_6 = uilabel(app.UnsharpMaskingInputPanel);
            app.gLabel_6.Text = 'c';
            app.UM_cEditField = uieditfield(app.UnsharpMaskingInputPanel, 'numeric');
            app.UM_cEditField.Limits = [-255 255];
            app.UM_cEditField.Value = 1;
            app.HighBoostFilterInputPanel = uipanel(app.UIFigure);
            app.HighBoostFilterInputPanel.Visible = 'off';
            app.gLabel_7 = uilabel(app.HighBoostFilterInputPanel);
            app.gLabel_7.Text = 'c';
            app.HBF_cEditField = uieditfield(app.HighBoostFilterInputPanel, 'numeric');
            app.HBF_cEditField.Limits = [-255 255];
            app.HBF_cEditField.Value = 1;
            app.cLabel_2 = uilabel(app.HighBoostFilterInputPanel);
            app.cLabel_2.Text = 'a';
            app.HBF_aEditField = uieditfield(app.HighBoostFilterInputPanel, 'numeric');
            app.HBF_aEditField.Limits = [-255 255];
            app.HBF_aEditField.Value = 1;
            app.InputImage = uiimage(app.UIFigure);
            app.RedChannelCheckBox = uicheckbox(app.UIFigure);
            app.RedChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResetHistogram, true);
            app.RedChannelCheckBox.Text = 'Red Channel';
            app.RedChannelCheckBox.Value = true;
            app.GreenChannelCheckBox = uicheckbox(app.UIFigure);
            app.GreenChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResetHistogram, true);
            app.GreenChannelCheckBox.Text = 'Green Channel';
            app.GreenChannelCheckBox.Value = true;
            app.BlueChannelCheckBox = uicheckbox(app.UIFigure);
            app.BlueChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResetHistogram, true);
            app.BlueChannelCheckBox.Text = 'BlueChannel';
            app.BlueChannelCheckBox.Value = true;
            app.InputFileEditFieldLabel = uilabel(app.UIFigure);
            app.InputFileEditFieldLabel.Text = 'Input File';
            app.InputFileEditField = uieditfield(app.UIFigure, 'text');
            app.InputFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputFileEditFieldValueChanged, true);
            app.RestorationMethodDropDownLabel = uilabel(app.UIFigure);
            app.RestorationMethodDropDownLabel.Text = 'Restoration Method';
            app.RestorationMethodDropDown = uidropdown(app.UIFigure);
            app.RestorationMethodDropDown.Items = {'Image Brightening', 'Contrast Stretching', 'Piecewise Linear Transform', 'Power Transform', 'Inverse Log Transform', 'Histogram Equalization', 'Histogram Specification', 'Averaging Filter', 'Median Filter', 'Gaussian Filter', 'Unsharp Masking', 'High Boost Filter'};
            app.RestorationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @RestorationMethodDropDownValueChanged, true);
            app.RestorationMethodDropDown.Value = 'Histogram Equalization';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.BrowseFileButton = uibutton(app.UIFigure, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButtonPushed, true);
            app.BrowseFileButton.Text = 'Browse File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end